classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ALabel            matlab.ui.control.Label
        Label             matlab.ui.control.Label
        ASeDropDown       matlab.ui.control.DropDown
        ASeDropDownLabel  matlab.ui.control.Label
        SnflandrButton    matlab.ui.control.Button
        Image             matlab.ui.control.Image
        ResimSeButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Button pushed function: ResimSeButton
        function ResimSeButtonPushed(app, event)
            
 [filename, pathname] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif', 'Supported Image Files'; '*.*', 'All Files'}, 'Fotograf Seciniz', 'MultiSelect', 'on');
    
    % Eğer bir dosya seçildiyse devam et
    if iscell(filename)
        % Birden fazla dosya seçilmişse, sadece ilk dosyayı al
        fullImagePath = fullfile(pathname, filename{1});
        
        % Seçilen fotoğrafı Image bileşenine yükle
        app.Image.ImageSource = fullImagePath;
    elseif ischar(filename)
        % Tek bir dosya seçildiyse
        fullImagePath = fullfile(pathname, filename);
        
        % Seçilen fotoğrafı Image bileşenine yükle
        app.Image.ImageSource = fullImagePath;
    end  

        
        end

        % Button pushed function: SnflandrButton
        function SnflandrButtonPushed(app, event)
selectedNetwork = app.ASeDropDown. Value;
selectedImagePath = app. Image. ImageSource;
selectedfile = fullfile (selectedImagePath);
I=imread(selectedfile);
I=imresize(I, [224 224]);
switch selectedNetwork
    case 'GoogleNet' 
    load C:\Users\onugu\OneDrive\Masaüstü\AlzheimerSınıflandırma\kodlar\googleNetRmsProp.mat;
net = net;
[labels] = classify (net, I);
app. Label. Text = labels;
app.ALabel. Text = 'GoogleNet Sonucu :';
case 'AlexNet'
    load C:\Users\onugu\OneDrive\Masaüstü\AlzheimerSınıflandırma\kodlar\Alexnetrmsprop.mat;
I=imresize (I, [227 227]);
net = trainedNetwork_2;
[labels] = classify (net, I);
app.Label. Text = labels;
app.ALabel. Text = 'AlexNet Sonucu :';

    case 'MyCNN'
    load C:\Users\onugu\OneDrive\Masaüstü\AlzheimerSınıflandırma\kodlar\myCNNRmsSuccess.mat;
net = myCNNRmsSuccess;
[labels] = classify(net,I);
app. Label. Text = labels;
app.ALabel.Text = 'MyCnn Sonucu :';
end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResimSeButton = uibutton(app.UIFigure, 'push');
            app.ResimSeButton.ButtonPushedFcn = createCallbackFcn(app, @ResimSeButtonPushed, true);
            app.ResimSeButton.Text = 'Resim Seç';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.SnflandrButton = uibutton(app.UIFigure, 'push');
            app.SnflandrButton.ButtonPushedFcn = createCallbackFcn(app, @SnflandrButtonPushed, true);
            app.SnflandrButton.Text = 'Sınıflandır';
            app.ASeDropDownLabel = uilabel(app.UIFigure);
            app.ASeDropDownLabel.Text = 'Ağ Seç';
            app.ASeDropDown = uidropdown(app.UIFigure);
            app.ASeDropDown.Items = {'MyCNN', 'AlexNet', 'GoogleNet'};
            app.ASeDropDown.Value = 'MyCNN';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'AĞ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end